classdef OAEDataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        OpenDataFolderButton  matlab.ui.control.Button
        DataFiles             matlab.ui.control.ListBox
        Print2eps             matlab.ui.control.Button
        ClearFigure           matlab.ui.control.Button
        text1                 matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        TabGroup              matlab.ui.container.TabGroup
        DPOAETab              matlab.ui.container.Tab
        PlotContentPannel     matlab.ui.container.Panel
        PrimaryRatio          matlab.ui.control.CheckBox
        PrimaryLevels         matlab.ui.control.CheckBox
        DPLevStd              matlab.ui.control.CheckBox
        NoiseFloor            matlab.ui.control.CheckBox
        hold_plot             matlab.ui.control.CheckBox
        MainFig               matlab.ui.control.UIAxes
        SubFigL               matlab.ui.control.UIAxes
        SubFigR               matlab.ui.control.UIAxes
        TEOAETab              matlab.ui.container.Tab
        DateLabel             matlab.ui.control.Label
        IDLabel               matlab.ui.control.Label
        WaveReproLabel        matlab.ui.control.Label
        NOISESPLLabel         matlab.ui.control.Label
        TEOAESPLLabel         matlab.ui.control.Label
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        DataPath % Description
        fio % Descriptio
        AllMatFiles % Description
        OAEFiles % Description
    end
    
    methods (Access = private)
        
        function PlotTEOAE(app,File)
            
            
            H = ProcessTEdata(app,File);
            set([app.UIAxes,app.UIAxes2, app.UIAxes3],'nextplot','replacechildren')
    %% 
    % spectrum and noise
    
            area(app.UIAxes2,H.TEOAE_data.Freq,H.TEOAE_data.spectrum,-40,...
            'facecolor','g');
            set(app.UIAxes2,'nextplot','add')
            area(app.UIAxes2,H.TEOAE_data.Freq,H.TEOAE_data.noise_spectrum,-40,...
            'facecolor','r');
            set(app.UIAxes2,'xlim',[300 6000])
            legend(app.UIAxes2,{'TEOAE' 'Noise'},'location','northeast','fontsize',14)
            legend(app.UIAxes2,'boxoff')

    %%
    % Time signals
            plot(app.UIAxes,H.TEOAE_data.T,H.TEOAE_data.Aav,'g-')
            set(app.UIAxes,'nextplot','add')
            plot(app.UIAxes,H.TEOAE_data.T,H.TEOAE_data.Bav,'m-')
            plot(app.UIAxes,H.TEOAE_data.T,H.TEOAE_data.noise,'linewidth',2,'color',[0.7 0.7 0.7])
            plot(app.UIAxes,H.TEOAE_data.T,H.TEOAE_data.mean,'linewidth',2)
            legend(app.UIAxes,{'A','B','noise','TEOAE'},'location','southwest','fontsize',8)
            legend(app.UIAxes,'boxoff');
    %%
    % Write subject and numeric info:
            SubjEar = sprintf('ID: %s; Ear: %s ',...
                H.TEOAE_data.ID,H.TEOAE_data.Ear);
            DateTime = sprintf('Date & Time: %s',...
                H.TEOAE_data.Mt{:});
            app.DateLabel.Text = DateTime;
            app.IDLabel.Text = SubjEar;
            NoiSPL = sprintf('Noise SPL: %4.1f dB',...
                roundn(H.TEOAE_data.noiseRMS,-1));
            app.NOISESPLLabel.Text = NoiSPL;
            TESPL = sprintf('TEOAE SPL: %4.1f dB',...
                roundn(H.TEOAE_data.RMS,-1));
            app.TEOAESPLLabel.Text = TESPL;
            WR = sprintf('Wave Repro: %3.4f %%', H.TEOAE_data.ABCorr);
            app.WaveReproLabel.Text = WR;
            %%
            % plot band analysis
            yyaxis(app.UIAxes3,"right")
            delete(app.UIAxes3.Children)
            app.UIAxes3.NextPlot = 'add';
                
            terms = plot(app.UIAxes3,[1:3],H.TEOAE_data.RMS_f,'gd-','markersize',5,...
                'markerfacecolor', 'g');
            noirms = plot(app.UIAxes3,[1:3],H.TEOAE_data.noiseRMS_f,'d-','markersize',5,...
                'color',[0.6 0.6 0.6]);
            
            RMS = plot(app.UIAxes3,4,H.TEOAE_data.RMS,'gd','markersize',6,...
                'markerfacecolor', 'g');
            noiseRMS = plot(app.UIAxes3,4,H.TEOAE_data.noiseRMS,'d','markersize',6,...
                'color',[0.6 0.6 0.6]);
            
            yyaxis(app.UIAxes3,"left")
            delete(app.UIAxes3.Children)
            app.UIAxes3.NextPlot = 'add';
            tecorr = plot(app.UIAxes3,[1:3],H.TEOAE_data.AB_f_Corr,'bs-','markersize',5,...
                'markerfacecolor', 'b');
             
            bbcorr = plot(app.UIAxes3,4,H.TEOAE_data.ABCorr,'bs','markersize',6,...
                'markerfacecolor', 'b');
            legend([bbcorr,RMS,noiseRMS],...
            {'Corr.','TEOAE Level','Noise Level'},'orientation','horizontal',...
            'location',[0.7 0.51 0.22 0.04])
            
           
            %%
            % set axes
            yyaxis(app.UIAxes3,"left")
            set(app.UIAxes3,'Xlim',[0 5],'ylim',[0 110],...
                'XGrid','off','YGrid','on','ytick',0:10:110,...
                'yticklabel',{'','','20','','40','' '60' '' '80' '' '100' ''},...
                'XTickLabel',{'1','2','4','BB'},'XTick',1:4)
            ylabel(app.UIAxes3,'Correlation Coefficient %')
            yyaxis(app.UIAxes3,"right")
            set(app.UIAxes3,'ylim',[-35 20],...
                'XGrid','off','YGrid','on','ytick',-35:5:20,...
                'yticklabel',{'','','-25','','-15','' '-5' '' '5' '' '15' ''})
            ylabel(app.UIAxes3,'SPL dB re. 20\muPa')
            xlabel(app.UIAxes3,'Frequency Band kHz')
            set(app.UIAxes,'xlim',[0 0.024],'xtick', 0.002:0.002:0.022,'xticklabel',2:2:22,...
                'ylim', [-2e-4 2e-4],'ytick', [-1e-4 0 1e-4],'yticklabel', [-0.1 0 0.1],...
                'xgrid','on','ygrid','on','box','on');
            ylabel(app.UIAxes,'Pressure [mPa]')
            xlabel(app.UIAxes,'Time [ms]')
            set(app.UIAxes2,'ylim',[-40 10], 'xlim', [300 6000],'xtick',1000:1000:6000,...
                'xticklabel',{'1' '2' '3' '4' '5' ''},'xgrid','on','ygrid','on',...
                'box','on');
            ylabel(app.UIAxes2,'SPL [dB re. 20\mu Pa]')
            xlabel(app.UIAxes2,'Frequency [kHz]')


end
% function Animate(Obj,Event)
%     H = guidata(Obj);
%     H = get_Selected(H);
%     H = ProcessTEdata(H);
%     set([app.UIAxes,app.UIAxes2],'nextplot','replacechildren');
%     legend([app.UIAxes,app.UIAxes2],'off');
%     T = H.TEOAE_data.T;
%     A = H.TEOAE_data.A;
%     B = H.TEOAE_data.B;
%     r=find(A(:,1)>0);
%     nfft=size(A,1); 
%     for i = 1:size(A,2)
%         Am = mean(A(:,1:i),2);
%         Bm = mean(B(:,1:i),2);
%         
%         AB = (Am+Bm)./2;
%         Noi = (Am - Bm)./2;
%         WavRep = corr(Am,Bm)*100;
%         
%         AB_RMS = 20*log10(sqrt(mean(AB(r(1):end).^2))/20e-6);
%         Noi_RMS = 20*log10(sqrt(mean(Noi(r(1):end).^2))/20e-6);
%         AB_FreqAmp = 2*abs(fft(AB))/nfft;
%         AB_FreqAmp = 20*log10(AB_FreqAmp(1:nfft/2)/20e-6);
%         Noi_FreqAmp = 2*abs(fft(Noi))/nfft;
%         Noi_FreqAmp = 20*log10(Noi_FreqAmp(1:nfft/2)/20e-6);
%         
%         plot(app.UIAxes,T,Am,'g-')
%         set(app.UIAxes,'nextplot','add')
%         plot(app.UIAxes,T,Bm,'m-')
%         plot(app.UIAxes,T,Noi,'linewidth',2,'color',[0.7 0.7 0.7])
%         plot(app.UIAxes,T,AB,'b-','linewidth',2)
%         legend(app.UIAxes,{'A','B','Noise','TEOAE'},'location','southwest','fontsize',8)
%         legend(app.UIAxes,'boxoff');
%         
%         
%         
%         area(app.UIAxes2,H.TEOAE_data.Freq,AB_FreqAmp,-40,...
%             'facecolor','g');
%         set(app.UIAxes2,'nextplot','add')
%         area(app.UIAxes2,H.TEOAE_data.Freq,Noi_FreqAmp,-40,...
%             'facecolor','r');
%         set(app.UIAxes2,'xlim',[300 6000])
%         legend(app.UIAxes2,{'TEOAE' 'Noise'},'location','northeast','fontsize',14)
%         legend(app.UIAxes2,'boxoff')
%         
%         
%         
%         NoiSPL = sprintf('Noise SPL:\n\n %4.1f dB',...
%             roundn(Noi_RMS,-1));
%         set(H.txtNoiSPL, 'string', NoiSPL)
%         TESPL = sprintf('TEOAE SPL:\n\n %4.1f dB',...
%             roundn(AB_RMS,-1));
%         set(H.txtTESPL, 'string', TESPL)
%         WR = sprintf('Wave Repro:\n\n %3.4f dB', WavRep);
%         set(H.txtWR, 'string', WR)
% 
%         PlotNr = sprintf('Plotting: %i of %i',i,size(A,2));
%         set(H.Measurement,'String',PlotNr,'Enable','off');
%         drawnow
%         pause(0.0001)
%         set([app.UIAxes, app.UIAxes2],'nextplot','replacechildren')
%     end
%     set(H.Measurement,'String','Animate','Enable','on');
% end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DPOAEviewer_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to DPOAEviewer (see VARARGIN)
            % add data path.
            %clear all plots
            set(app.MainFig, 'Visible', 'off')
            set(app.SubFigL, 'Visible', 'off')
            set(app.SubFigR, 'Visible', 'off')
            
            %initialize variables:
            handles.holdplot = 0;
            handles.Primarylevelplot = 0;
            handles.Primaryratioplot = 0;
            handles.DPstdplot = 0;
            handles.noisefloorplot = 0;
            handles.numberofplots = 0;
            handles.col = {'b-' 'r-' 'g-' 'm-' 'c-' 'y-'};
            handles.coll = {'bo-' 'rd-' 'gv-' 'm^-' 'c<-' 'y>-'};
            handles.colll = {'b' 'r' 'g' 'm' 'c' 'y'};
            
            % Choose default command line output for DPOAEviewer
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes DPOAEviewer wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
           
            % 
            app.ClearFigure.Enable = "off";
            app.Print2eps.Enable = "off";
            app.figure1.Resize = 'on';
            
        end

        % Button pushed function: ClearFigure
        function ClearFigure_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ClearFigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.MainFig, 'Visible', 'off')
            cla(handles.MainFig)
            set(handles.SubFigL, 'Visible', 'off')
            cla(handles.SubFigL)
            set(handles.SubFigR, 'Visible', 'off')
            cla(handles.SubFigR)
            set(handles.PrimaryRatio, 'value', 0);
            set(handles.PrimaryLevels, 'value', 0);
            set(handles.DPLevStd, 'value', 0);
            set(handles.NoiseFloor, 'value', 0);
            set(handles.hold_plot, 'value', 0);
            
            handles.p1 = 0;
            handles.numberofplots = 0;
            handles.holdplot = 0;
            handles.Primarylevelplot = 0;
            handles.Primaryratioplot = 0;
            handles.DPstdplot = 0;
            handles.noisefloorplot = 0;
            handles.numberofplots = 0;
            guidata(hObject, handles);
        end

        % Value changed function: DPLevStd
        function DPLevStd_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to DPLevStd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of DPLevStd
            if get(hObject,'Value')
                handles.DPstdplot = 1;
                handles = DPstdplot(handles);
            else
                handles.DPstdplot = 0;
                if ~isempty(handles.wiskers) && ishandle(handles.wiskers(end))
                    delete(handles.wiskers(end));
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: NoiseFloor
        function NoiseFloor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to NoiseFloor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of NoiseFloor
            if get(hObject,'Value')
                handles.noisefloorplot = 1;
                handles = Noisefloorplot(handles);
            else
                handles.noisefloorplot = 0;
                if ~isempty(handles.p2(:,handles.numberofplots)) &&...
                        sum(ishandle(handles.p2(:,handles.numberofplots)))
                    delete(handles.p1(handles.numberofplots))
                    delete(handles.p2(:,handles.numberofplots))
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: PrimaryLevels
        function PrimaryLevels_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PrimaryLevels (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of PrimaryLevels
            if get(hObject,'Value')
                handles.Primarylevelplot = 1;
                handles = Primarylevelplot(handles);
            else
                handles.Primarylevelplot = 0;
                if ~isempty(handles.plplot) && ishandle(handles.plplot(end))
                    delete(handles.plplot(1:end))
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: PrimaryRatio
        function PrimaryRatio_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PrimaryRatio (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of PrimaryRatio
            if get(hObject,'Value')
                handles.Primaryratioplot = 1;
                handles = Primaryratioplot(handles);
            else
                handles.Primaryratioplot = 0;
                if ~isempty(handles.Priratioplot) && ishandle(handles.Priratioplot)
                    delete(handles.Priratioplot)
                end
            end
            guidata(hObject, handles);
        end

        % Button pushed function: Print2eps
        function Print2eps_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Print2eps (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            FileName = uigetfile('Choose a file to save');
            PrintFig = figure;
            ax = axes('parent',PrintFig);
            ObjectHandles = get(handles.MainFig,'children');
            PrintObjects = copyobj(ObjectHandles,ax);
            set(ax,'xlim',[700 3500],'ylim',[-30 25], 'ytick', [-25:5:25], ...
                'box','on', 'Xgrid','on', 'ygrid','on', 'nextplot','add','fontsize',14);
            %AX = [get(ax,'Xlim') get(ax,'ylim')];
            
            xlabel('Frequency [Hz]','fontsize',14);
            exportgraphics(PrintFig,FileName);
            close(PrintFig)
            guidata(hObject, handles);
        end

        % Value changed function: hold_plot
        function hold_plot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hold_plot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of hold_plot
            if get(hObject,'Value')
                handles.holdplot = 1;
                set(handles.SubFigL,'NextPlot','add');
                set(handles.SubFigR,'NextPlot','add');
                set(handles.MainFig,'NextPlot','add');
            
            else
                set(handles.SubFigL,'NextPlot','replacechildren');
                set(handles.SubFigR,'NextPlot','replacechildren');
                set(handles.MainFig,'NextPlot','replacechildren');
                handles.holdplot = 0;
                handles.numberofplots = 0;
            end
            guidata(hObject, handles);
        end

        % Value changed function: DataFiles
        function DataFiles_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            File = app.OAEFiles(strcmp({app.OAEFiles.name},eventdata.Value));
                        
            if numel(File) == 1
                app.fio = matfile([File.folder, '\',File.name],"Writable",false); 
                if strcmp(app.TabGroup.SelectedTab.Title,'DPOAE')
                    handles = dpcalc(app,handles);
                    handles = dpprocess(File.name,handles);
                    guidata(hObject, handles);
                else
                    PlotTEOAE(app,File.name);
                end
            else
                msgbox('No joy! ... try again.')
            end
        end

        % Button pushed function: OpenDataFolderButton
        function OpenDataFolderButtonPushed(app, event)
            app.DataPath = uigetdir;
            if app.DataPath ~= 0

                if strcmp(app.TabGroup.SelectedTab.Title,'DPOAE')
                    app.OAEFiles = dir([app.DataPath,'/*DP*.mat']);
                    app.DataFiles.Items = {app.OAEFiles.name};
                    app.ClearFigure.Enable = "on";
                else
                    app.OAEFiles = dir([app.DataPath,'/*TE*.mat']);
                    app.DataFiles.Items = {app.OAEFiles.name};
                    app.ClearFigure.Enable = "off";
                end
               
            else
               msgbox('Did not get a valid folder... try again.')
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title,'DPOAE')
                app.OAEFiles = dir([app.DataPath,'/*DP*.mat']);
                app.DataFiles.Items = {app.OAEFiles.name};
                app.ClearFigure.Enable = "on";
            else
                app.OAEFiles = dir([app.DataPath,'/*TE*.mat']);
                app.DataFiles.Items = {app.OAEFiles.name};
                app.ClearFigure.Enable = "off";
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.figure1.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {682, 682};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {182, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DPOAEviewer';
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.LeftPanel = uipanel(app.GridLayout);
            app.text1 = uilabel(app.LeftPanel);
            app.text1.Tag = 'text1';
            app.text1.Text = 'DATA DIRECTORY';
            app.ClearFigure = uibutton(app.LeftPanel, 'push');
            app.ClearFigure.ButtonPushedFcn = createCallbackFcn(app, @ClearFigure_Callback, true);
            app.ClearFigure.Tag = 'ClearFigure';
            app.ClearFigure.Text = 'Clear Figure';
            app.Print2eps = uibutton(app.LeftPanel, 'push');
            app.Print2eps.ButtonPushedFcn = createCallbackFcn(app, @Print2eps_Callback, true);
            app.Print2eps.Tag = 'Print2eps';
            app.Print2eps.Enable = 'off';
            app.Print2eps.Visible = 'off';
            app.Print2eps.Text = 'Print to file';
            app.DataFiles = uilistbox(app.LeftPanel);
            app.DataFiles.Items = {'files', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', 'Item 11', 'Item 12'};
            app.DataFiles.ValueChangedFcn = createCallbackFcn(app, @DataFiles_Callback, true);
            app.DataFiles.Tag = 'listbox1';
            app.DataFiles.Value = 'files';
            app.OpenDataFolderButton = uibutton(app.LeftPanel, 'push');
            app.OpenDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataFolderButtonPushed, true);
            app.OpenDataFolderButton.Text = 'Open Data Folder';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DPOAETab = uitab(app.TabGroup);
            app.DPOAETab.Title = 'DPOAE';
            app.SubFigR = uiaxes(app.DPOAETab);
            app.SubFigR.Tag = 'SubFigR';
            app.SubFigL = uiaxes(app.DPOAETab);
            app.SubFigL.Tag = 'SubFigL';
            app.MainFig = uiaxes(app.DPOAETab);
            app.MainFig.Tag = 'MainFig';
            app.PlotContentPannel = uipanel(app.DPOAETab);
            app.PlotContentPannel.Title = 'Panel';
            app.PlotContentPannel.Tag = 'uipanel1';
            app.hold_plot = uicheckbox(app.PlotContentPannel);
            app.hold_plot.ValueChangedFcn = createCallbackFcn(app, @hold_plot_Callback, true);
            app.hold_plot.Tag = 'hold_plot';
            app.hold_plot.Text = 'Hold plot';
            app.NoiseFloor = uicheckbox(app.PlotContentPannel);
            app.NoiseFloor.ValueChangedFcn = createCallbackFcn(app, @NoiseFloor_Callback, true);
            app.NoiseFloor.Tag = 'NoiseFloor';
            app.NoiseFloor.Text = 'Noise floor';
            app.DPLevStd = uicheckbox(app.PlotContentPannel);
            app.DPLevStd.ValueChangedFcn = createCallbackFcn(app, @DPLevStd_Callback, true);
            app.DPLevStd.Tag = 'DPLevStd';
            app.DPLevStd.Text = 'DP level STD';
            app.PrimaryLevels = uicheckbox(app.PlotContentPannel);
            app.PrimaryLevels.ValueChangedFcn = createCallbackFcn(app, @PrimaryLevels_Callback, true);
            app.PrimaryLevels.Tag = 'PrimaryLevels';
            app.PrimaryLevels.Text = 'Primary Levels';
            app.PrimaryRatio = uicheckbox(app.PlotContentPannel);
            app.PrimaryRatio.ValueChangedFcn = createCallbackFcn(app, @PrimaryRatio_Callback, true);
            app.PrimaryRatio.Tag = 'PrimaryRatio';
            app.PrimaryRatio.Text = 'Primary Ratio';
            app.TEOAETab = uitab(app.TabGroup);
            app.TEOAETab.Title = 'TEOAE';
            app.UIAxes = uiaxes(app.TEOAETab);
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Pressure [mPa]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.TEOAETab);
            xlabel(app.UIAxes2, 'Frequency [kHz]')
            ylabel(app.UIAxes2, 'SPL [dB re. 20\mu Pa]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.TEOAETab);
            xlabel(app.UIAxes3, 'Frequency Band kHz')
            app.UIAxes3.XLim = [0 5];
            app.UIAxes3.XTick = [1 2 3 4];
            app.UIAxes3.XTickLabel = {'1'; '2'; '4'; 'BB'};
            app.TEOAESPLLabel = uilabel(app.TEOAETab);
            app.TEOAESPLLabel.Text = 'TEOAE SPL: ';
            app.NOISESPLLabel = uilabel(app.TEOAETab);
            app.NOISESPLLabel.Text = 'NOISE SPL:';
            app.WaveReproLabel = uilabel(app.TEOAETab);
            app.WaveReproLabel.Text = 'Wave Repro: ';
            app.IDLabel = uilabel(app.TEOAETab);
            app.IDLabel.Text = 'ID:';
            app.DateLabel = uilabel(app.TEOAETab);
            app.DateLabel.Text = 'Date:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OAEDataViewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)DPOAEviewer_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end