classdef TEOAEDataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        OpenDataFolderButton           matlab.ui.control.Button
        DataFiles                      matlab.ui.control.ListBox
        Print2eps                      matlab.ui.control.Button
        ClearFigure                    matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        EarEditField                   matlab.ui.control.EditField
        EarEditFieldLabel              matlab.ui.control.Label
        WaveReproEditField_2           matlab.ui.control.EditField
        WaveReproEditField_2Label      matlab.ui.control.Label
        NoiseLevelCLSdBEditField       matlab.ui.control.EditField
        NoiseLevelCLSdBEditFieldLabel  matlab.ui.control.Label
        OAELevelCLSdBEditField         matlab.ui.control.EditField
        OAELevelCLSdBEditFieldLabel    matlab.ui.control.Label
        WaveReproEditField             matlab.ui.control.EditField
        WaveReproEditFieldLabel        matlab.ui.control.Label
        NoiseLeveldBEditField          matlab.ui.control.EditField
        NoiseLeveldBEditFieldLabel     matlab.ui.control.Label
        OAELeveldBEditField            matlab.ui.control.EditField
        OAELeveldBEditFieldLabel       matlab.ui.control.Label
        DateEditField                  matlab.ui.control.EditField
        DateEditFieldLabel             matlab.ui.control.Label
        IDEditField                    matlab.ui.control.EditField
        IDEditFieldLabel               matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        DataPath % Path to results
        fio % Current matlab file handle
        AllMatFiles % lest of availables files
        OAEFiles % Description
    end
    
    methods (Access = private)
        
        function PlotTEOAE(app)
            H = ProcessTE_CLSdata(app.fio);
            % average multiple measurements
            Measurements = who(app.fio);
            Info = app.fio.(Measurements{1});
            Config = Info.Config;
            Data = Info.Data;

                OAELev = mean(cat(1,H.TEOAE(:).mean),1);
                OAESLev = mean(cat(1,H.TEOAE_CLS(:).mean),1);
                NLev = mean(cat(1,H.TEOAE(:).noise),1);
                NSLev = mean(cat(1,H.TEOAE_CLS(:).noise),1);
                OAESpec = mean(cat(1,H.TEOAE(:).spectrum),1);
                OAESSpec = mean(cat(1,H.TEOAE_CLS(:).spectrum),1);
                nSpec = mean(cat(1,H.TEOAE(:).noise_spectrum),1);
                nsSpec = mean(cat(1,H.TEOAE_CLS(:).noise_spectrum),1);
                CorrF = mean(cat(1,H.TEOAE(:).AB_f_Corr),1);
                CorrSF = mean(cat(1,H.TEOAE_CLS(:).AB_f_Corr),1);
                CorrBB = mean(cat(1,H.TEOAE(:).ABCorr),1);
                CorrSBB = mean(cat(1,H.TEOAE_CLS(:).ABCorr),1);
                rmsF = mean(cat(1,H.TEOAE(:).RMS_f),1);
                rmsSF = mean(cat(1,H.TEOAE_CLS(:).RMS_f),1);
                noisermsF = mean(cat(1,H.TEOAE(:).noiseRMS_f),1);
                noisermsSF = mean(cat(1,H.TEOAE_CLS(:).noiseRMS_f),1);
                rmsBB = mean(cat(1,H.TEOAE(:).RMS),1);
                rmsSBB = mean(cat(1,H.TEOAE_CLS(:).RMS),1);
                noisermsBB = mean(cat(1,H.TEOAE(:).noiseRMS),1);
                noisermsSBB = mean(cat(1,H.TEOAE_CLS(:).noiseRMS),1);
            delete(app.UIAxes.Children)
            delete(app.UIAxes2.Children)
            delete(app.UIAxes3.Children)
            
            %set([app.UIAxes,app.UIAxes2, app.UIAxes3],'nextplot','replacechildren')
    %% 
    % spectrum and noise
            OAECol = app.OAELeveldBEditFieldLabel.BackgroundColor;
            OAESCol = app.OAELevelCLSdBEditFieldLabel.BackgroundColor;
            NCol =  OAECol - [0.2 0.2 0];
            NSCol = OAESCol - [0 0.2 0.2];
            line(app.UIAxes2,H.TEOAE(end).Freq,OAESpec,...
            'color',OAECol,'linewidth',2);
            set(app.UIAxes2,'nextplot','add')
            line(app.UIAxes2,H.TEOAE(end).Freq,nSpec,...
            'color',NCol);

            line(app.UIAxes2,H.TEOAE_CLS(end).Freq,OAESSpec,...
            'color',OAESCol,'linewidth',2);
            set(app.UIAxes2,'nextplot','add')
            line(app.UIAxes2,H.TEOAE_CLS(end).Freq,nsSpec,...
            'color',NSCol);

            set(app.UIAxes2,'xlim',[300 6000])
           
            legend(app.UIAxes2,{'OAE' 'Noise' 'OAE CLS' 'Noise CLS'},'location','northeast','fontsize',14)
            legend(app.UIAxes2,'boxoff')

    %%
    % Time signals
            line(app.UIAxes,H.TEOAE(end).T,OAELev,'color', OAECol,'linewidth',2)
            set(app.UIAxes,'nextplot','add')
            line(app.UIAxes,H.TEOAE(end).T,NLev,'linewidth',2,'color',NCol)
            line(app.UIAxes,H.TEOAE_CLS(end).T,OAESLev,'color', OAESCol,'linewidth',2)
            line(app.UIAxes,H.TEOAE_CLS(end).T,NSLev,'linewidth',2,'color',NSCol)
            
            
            legend(app.UIAxes,{'OAE','Noise','OAE CLS','Noise CLS'},'location','southwest','fontsize',8)
            legend(app.UIAxes,'boxoff');
    %%
    % Write subject and numeric info:
            temp = regexp(app.fio.Properties.Source,'\','split');
            app.IDEditField.Value = temp{end}(1:end-4);
            app.EarEditField.Value = Data.Ear;
            app.DateEditField.Value = Data.Date;
            app.OAELevelCLSdBEditField.Value = num2str(rmsSBB);
            app.OAELeveldBEditField.Value = num2str(rmsBB);
            app.NoiseLevelCLSdBEditField.Value = num2str(noisermsSBB);
            app.NoiseLeveldBEditField.Value = num2str(noisermsBB);
            app.WaveReproEditField_2.Value = num2str(CorrSBB);
            app.WaveReproEditField.Value = num2str(CorrBB);
            %%
            % plot band analysis
            yyaxis(app.UIAxes3,"right")
            delete(app.UIAxes3.Children)
            app.UIAxes3.NextPlot = 'add';
            app.UIAxes3.YAxis(2).Color = OAECol;    
            terms = plot(app.UIAxes3,[1:3]-0.2,rmsF,'>-','markersize',5,...
                'markerfacecolor', OAECol,'Color',OAECol);
            noirms = plot(app.UIAxes3,[1:3]-0.2,noisermsF,'>-','markersize',5,...
                'markerfacecolor', NCol, 'Color',NCol);
            
            RMS = plot(app.UIAxes3,4-0.2,rmsBB,'>','markersize',6,...
                'markerfacecolor', OAECol,'Color', OAECol);
            noiseRMS = plot(app.UIAxes3,4-0.2,noisermsBB,'>','markersize',6,...
                'markerfacecolor',NCol,'Color', NCol);
            
            terms = plot(app.UIAxes3,[1:3],rmsSF,'<-','markersize',5,...
                'markerfacecolor',OAECol,'Color',OAECol);
            noirms = plot(app.UIAxes3,[1:3],noisermsSF,'<-','markersize',5,...
                'color',NCol,'markerfacecolor',NCol);
            
            RMSS = plot(app.UIAxes3,4,rmsSBB,'<','markersize',6,...
                'markerfacecolor', OAECol,'Color',OAECol);
            noiseRMS = plot(app.UIAxes3,4,noisermsSBB,'<','markersize',6,...
                'color',NCol,'MarkerFaceColor',NCol);


            yyaxis(app.UIAxes3,"left")
            delete(app.UIAxes3.Children)
            app.UIAxes3.NextPlot = 'add';
            app.UIAxes3.YAxis(1).Color = OAESCol;
            tecorr = plot(app.UIAxes3,[1:3]+0.2,CorrF,'>-','markersize',5,...
                'markerfacecolor', OAESCol, 'Color', OAESCol);
            tecorrS = plot(app.UIAxes3,[1:3]+0.2,CorrSF,'<-','markersize',5,...
                'markerfacecolor', OAESCol, 'Color', OAESCol); 
            bbcorr = plot(app.UIAxes3,4.2,CorrBB,'>','markersize',6,...
                'markerfacecolor', OAESCol, 'color', OAESCol);
            bbcorrS = plot(app.UIAxes3,4.2,CorrSBB,'<','markersize',6,...
                'markerfacecolor', OAESCol, 'color', OAESCol);
            legend([RMS,RMSS],...
            {'OAE','OAE CLS'},'orientation','vertical',...
            'location','northwest');
            
           
            %%
            % set axes
            yyaxis(app.UIAxes3,"left")
            set(app.UIAxes3,'Xlim',[0 5],'ylim',[0 110],...
                'XGrid','off','YGrid','on','ytick',0:10:110,...
                'yticklabel',{'','','20','','40','' '60' '' '80' '' '100' ''},...
                'XTickLabel',{'1','2','4','BB'},'XTick',1:4)
            ylabel(app.UIAxes3,'Correlation Coefficient %')
            yyaxis(app.UIAxes3,"right")
            set(app.UIAxes3,'ylim',[-35 20],...
                'XGrid','off','YGrid','on','ytick',-35:5:20,...
                'yticklabel',{'','','-25','','-15','' '-5' '' '5' '' '15' ''})
            ylabel(app.UIAxes3,'SPL dB re. 20\muPa')
            xlabel(app.UIAxes3,'Frequency Band kHz')
            set(app.UIAxes,'xlim',[0 0.024],'xtick', 0.002:0.002:0.022,'xticklabel',2:2:22,...
                'ylim', [-2e-4 2e-4],'ytick', [-1e-4 0 1e-4],'yticklabel', [-0.1 0 0.1],...
                'xgrid','on','ygrid','on','box','on');
            ylabel(app.UIAxes,'Pressure [mPa]')
            xlabel(app.UIAxes,'Time [ms]')
            set(app.UIAxes2,'ylim',[-40 10], 'xlim', [300 6000],'xtick',1000:1000:6000,...
                'xticklabel',{'1' '2' '3' '4' '5' ''},'xgrid','on','ygrid','on',...
                'box','on');
            ylabel(app.UIAxes2,'SPL [dB re. 20\mu Pa]')
            xlabel(app.UIAxes2,'Frequency [kHz]')


        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearFigure
        function ClearFigure_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ClearFigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.MainFig, 'Visible', 'off')
            cla(handles.MainFig)
            set(handles.SubFigL, 'Visible', 'off')
            cla(handles.SubFigL)
            set(handles.SubFigR, 'Visible', 'off')
            cla(handles.SubFigR)
            set(handles.PrimaryRatio, 'value', 0);
            set(handles.PrimaryLevels, 'value', 0);
            set(handles.DPLevStd, 'value', 0);
            set(handles.NoiseFloor, 'value', 0);
            set(handles.hold_plot, 'value', 0);
            
            handles.p1 = 0;
            handles.numberofplots = 0;
            handles.holdplot = 0;
            handles.Primarylevelplot = 0;
            handles.Primaryratioplot = 0;
            handles.DPstdplot = 0;
            handles.noisefloorplot = 0;
            handles.numberofplots = 0;
            guidata(hObject, handles);
        end

        % Button pushed function: Print2eps
        function Print2eps_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Print2eps (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            FileName = uigetfile('Choose a file to save');
            PrintFig = figure;
            ax = axes('parent',PrintFig);
            ObjectHandles = get(handles.MainFig,'children');
            PrintObjects = copyobj(ObjectHandles,ax);
            set(ax,'xlim',[700 3500],'ylim',[-30 25], 'ytick', [-25:5:25], ...
                'box','on', 'Xgrid','on', 'ygrid','on', 'nextplot','add','fontsize',14);
            %AX = [get(ax,'Xlim') get(ax,'ylim')];
            
            xlabel('Frequency [Hz]','fontsize',14);
            exportgraphics(PrintFig,FileName);
            close(PrintFig)
            guidata(hObject, handles);
        end

        % Value changed function: DataFiles
        function DataFiles_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            File = app.OAEFiles(strcmp({app.OAEFiles.name},event.Value));
                        
            if ~isempty(File)
                app.fio = matfile([File.folder, '\',File.name],"Writable",false); 
                PlotTEOAE(app);
              
            else
                msgbox('No joy! ... try again.')
            end
        end

        % Button pushed function: OpenDataFolderButton
        function OpenDataFolderButtonPushed(app, event)
            app.DataPath = uigetdir;
            if app.DataPath ~= 0
                app.OAEFiles = dir([app.DataPath,'/*.mat']);
                app.DataFiles.Items = {app.OAEFiles.name};
            else
               msgbox('Did not get a valid folder... try again.')
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.figure1.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {682, 682};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {182, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DPOAEviewer';
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.LeftPanel = uipanel(app.GridLayout);
            app.text1 = uilabel(app.LeftPanel);
            app.text1.Tag = 'text1';
            app.text1.Text = 'DATA DIRECTORY';
            app.ClearFigure = uibutton(app.LeftPanel, 'push');
            app.ClearFigure.ButtonPushedFcn = createCallbackFcn(app, @ClearFigure_Callback, true);
            app.ClearFigure.Tag = 'ClearFigure';
            app.ClearFigure.Text = 'Clear Figure';
            app.Print2eps = uibutton(app.LeftPanel, 'push');
            app.Print2eps.ButtonPushedFcn = createCallbackFcn(app, @Print2eps_Callback, true);
            app.Print2eps.Tag = 'Print2eps';
            app.Print2eps.Enable = 'off';
            app.Print2eps.Visible = 'off';
            app.Print2eps.Text = 'Print to file';
            app.DataFiles = uilistbox(app.LeftPanel);
            app.DataFiles.Items = {'files', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', 'Item 11', 'Item 12'};
            app.DataFiles.ValueChangedFcn = createCallbackFcn(app, @DataFiles_Callback, true);
            app.DataFiles.Tag = 'listbox1';
            app.DataFiles.Value = 'files';
            app.OpenDataFolderButton = uibutton(app.LeftPanel, 'push');
            app.OpenDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataFolderButtonPushed, true);
            app.OpenDataFolderButton.Text = 'Open Data Folder';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Pressure [mPa]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes2, 'Frequency [kHz]')
            ylabel(app.UIAxes2, 'SPL [dB re. 20\mu Pa]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes3, 'Frequency Band kHz')
            app.UIAxes3.XLim = [0 5];
            app.UIAxes3.XTick = [1 2 3 4];
            app.UIAxes3.XTickLabel = {'1'; '2'; '4'; 'BB'};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.IDEditFieldLabel = uilabel(app.GridLayout3);
            app.IDEditFieldLabel.Text = 'ID:';
            app.IDEditField = uieditfield(app.GridLayout3, 'text');
            app.DateEditFieldLabel = uilabel(app.GridLayout3);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.GridLayout3, 'text');
            app.OAELeveldBEditFieldLabel = uilabel(app.GridLayout3);
            app.OAELeveldBEditFieldLabel.Text = 'OAE Level [dB]';
            app.OAELeveldBEditField = uieditfield(app.GridLayout3, 'text');
            app.NoiseLeveldBEditFieldLabel = uilabel(app.GridLayout3);
            app.NoiseLeveldBEditFieldLabel.Text = 'Noise Level [dB]';
            app.NoiseLeveldBEditField = uieditfield(app.GridLayout3, 'text');
            app.WaveReproEditFieldLabel = uilabel(app.GridLayout3);
            app.WaveReproEditFieldLabel.Text = 'Wave Repro [%] ';
            app.WaveReproEditField = uieditfield(app.GridLayout3, 'text');
            app.OAELevelCLSdBEditFieldLabel = uilabel(app.GridLayout3);
            app.OAELevelCLSdBEditFieldLabel.Text = 'OAE Level - CLS [dB]';
            app.OAELevelCLSdBEditField = uieditfield(app.GridLayout3, 'text');
            app.NoiseLevelCLSdBEditFieldLabel = uilabel(app.GridLayout3);
            app.NoiseLevelCLSdBEditFieldLabel.Text = 'Noise Level - CLS [dB]';
            app.NoiseLevelCLSdBEditField = uieditfield(app.GridLayout3, 'text');
            app.WaveReproEditField_2Label = uilabel(app.GridLayout3);
            app.WaveReproEditField_2Label.Text = 'Wave Repro [%]';
            app.WaveReproEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.EarEditFieldLabel = uilabel(app.GridLayout3);
            app.EarEditFieldLabel.Text = 'Ear';
            app.EarEditField = uieditfield(app.GridLayout3, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEOAEDataViewer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end