classdef OAEMeasurement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Calibration                     matlab.ui.container.Menu
        Subject                         matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        SoundCard                       matlab.ui.container.Menu
        ChooseSoundCardMenu             matlab.ui.container.Menu
        ResetSoundCardMenu              matlab.ui.container.Menu
        Tools                           matlab.ui.container.Menu
        DataViewerMenu                  matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayoutLeft                  matlab.ui.container.GridLayout
        StartButton                     matlab.ui.control.Button
        GridCurrMeas                    matlab.ui.container.GridLayout
        EarEditField                    matlab.ui.control.EditField
        EarEditFieldLabel               matlab.ui.control.Label
        SubjectIDEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        CurrentMeasurementLabel         matlab.ui.control.Label
        MeasurementParametersLabel      matlab.ui.control.Label
        GridMeasParam                   matlab.ui.container.GridLayout
        ContralateralStimulationSwitch  matlab.ui.control.Switch
        ContralateralStimulationSwitchLabel  matlab.ui.control.Label
        FrequencyofCheckFitmonitorEditField  matlab.ui.control.NumericEditField
        FrequencyofCheckFitmonitorEditFieldLabel  matlab.ui.control.Label
        CheckfitImpulselengthmsEditField  matlab.ui.control.NumericEditField
        CheckfitImpulselengthmsEditFieldLabel  matlab.ui.control.Label
        CheckfitImpulseleveldBSPLEditField  matlab.ui.control.NumericEditField
        CheckfitImpulseleveldBSPLEditFieldLabel  matlab.ui.control.Label
        LowpasscutoffHzEditField        matlab.ui.control.NumericEditField
        LowpasscutoffHzEditFieldLabel   matlab.ui.control.Label
        HighPasscutoffHzEditField       matlab.ui.control.NumericEditField
        HighPasscutoffHzEditFieldLabel  matlab.ui.control.Label
        NumberofmeasurementsEditField   matlab.ui.control.NumericEditField
        NumberofmeasurementsEditFieldLabel  matlab.ui.control.Label
        StimulusspacingmsEditField      matlab.ui.control.NumericEditField
        StimulusspacingmsEditFieldLabel  matlab.ui.control.Label
        TimeRejectionmsEditField        matlab.ui.control.NumericEditField
        TimeRejectionmsEditFieldLabel   matlab.ui.control.Label
        NoiserejectionleveldBSPLEditField  matlab.ui.control.NumericEditField
        NoiserejectionleveldBSPLEditFieldLabel  matlab.ui.control.Label
        MaxLeveldBSPLEditField          matlab.ui.control.NumericEditField
        MaxLeveldBSPLEditFieldLabel     matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayoutRight                 matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        GridChedfit                     matlab.ui.container.GridLayout
        UIAxesCheckFitFreq              matlab.ui.control.UIAxes
        UIAxesCheckFitTime              matlab.ui.control.UIAxes
        AXSpecT                         matlab.ui.control.UIAxes
        GridLayout2                     matlab.ui.container.GridLayout
        MeasInfoTab                     matlab.ui.control.Table
        AxTimeSig                       matlab.ui.control.UIAxes
        TEOAEMeasurementLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SubjectID % response file name and path
        FileIO % mat file handle to store and read data
    end
    properties (Access = public)
        AuIO % Sound card object
        Data % Measurement data structure for new data
        Config % Current measurement configuration
    end
    
    methods (Access = private)
        
        function ReadTestParameters(app)
            app.Config.ContralateralStim = app.ContralateralStimulationSwitch.Value;
            app.Config.CheckFit.MonitorTiming = app.FrequencyofCheckFitmonitorEditField.Value; 
            app.Config.CheckFit.Impulselength = app.CheckfitImpulselengthmsEditField.Value/1000; 
            app.Config.CheckFit.dBSPL = app.CheckfitImpulseleveldBSPLEditField.Value; 
            app.Config.BandPassFilter = [app.LowpasscutoffHzEditField.Value ... 
            app.HighPasscutoffHzEditField.Value]; 
            app.Config.NumberofMeasurements = app.NumberofmeasurementsEditField.Value; 
            app.Config.Stimulusspacing = app.StimulusspacingmsEditField.Value/1000; 
            app.Config.TimeRejection = app.TimeRejectionmsEditField.Value/1000;
            app.Config.NoiseRejectionLevel = app.NoiserejectionleveldBSPLEditField.Value; 
            app.Config.MaxLeveldBSPL = app.MaxLeveldBSPLEditField.Value;
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MeasInfoTab.Data = cell(7,1);
            app.Data = struct(...
                'A',[],'B',[],'Stimulus',[],'Ear','','PatientID','',...
                'Date','','StartTime','','EndTime','','ProbeFitL',[],...
                'ProbeFitR',[]);
            app.Config = struct(...
                'Fs', [],'TEdBPeak', [],'NumofMeasurements', [],...
                'TimeRejection', [],'NoiseThreshold', [],...
                'BandPassFilter', [], 'CheckFit', struct);
            app.StartButton.Enable = 'off';
        end

        % Menu selected function: ChooseSoundCardMenu
        function ChooseSoundCardMenuSelected(app, event)
            ssf = SoundCardSelection(app);
            waitfor(ssf)
            if app.AuIO.isLocked
                MsG = sprintf('\n Sound Card: %s is initialized and ready!',app.AuIO.Device);
                uiconfirm(app.UIFigure,MsG,'Sound Set-up','Options',{'Ok'},'Icon','success');
                app.ChooseSoundCardMenu.Enable = "off";
                app.ChooseSoundCardMenu.Text = app.AuIO.Device;
                app.ChooseSoundCardMenu.Checked = true;
            else 
                MsG = sprintf('\n Sound Card: %s is not connected or is in use by another object.',app.AuIO.Device);
                uiconfirm(app.UIFigure,MsG,'Sound Set-up','options',{'Ok'},'Icon','Error');
            end    
        end

        % Menu selected function: ResetSoundCardMenu
        function ResetSoundCardMenuSelected(app, event)
            release(app.AuIO)
            app.ChooseSoundCardMenu.Text = 'Choose Sound Card';
            app.ChooseSoundCardMenu.Checked = "off";
            app.ChooseSoundCardMenu.Enable = "on";
            MsG = 'Sound card has been reset, Choose a new sound card.';
            uiconfirm(app.UIFigure,MsG,'Sound Set-up','options',{'Ok'},'Icon','warning');
        end

        % Menu selected function: LoadMenu, NewMenu
        function loadMenuSelected(app, event)
             d = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message","Reading data...");

            if strcmp(event.Source.Text,'Load')
                [app.SubjectID.Name, app.SubjectID.Path] = uigetfile('data\*.mat','Select existing data file.');
                
            else
                [app.SubjectID.Name, app.SubjectID.Path] = uiputfile('data\*.mat','Type the name of a new data file.');
                
            end
            
            if app.SubjectID.Name ~= 0
                app.FileIO = matfile([app.SubjectID.Path,app.SubjectID.Name],"Writable",true);
                %get ready to start.
                app.SubjectIDEditField.Value = app.SubjectID.Name(1:end-4);
                app.Data.PatientID = app.SubjectIDEditField.Value;
                focus(app.EarEditField);
                ReadTestParameters(app);
                
            else
                uialert(app.UIFigure,'No Patient file was loaded or created, try again.','','Icon','error')
                app.FileIO = 0;

            end
            close(d)

        end

        % Value changed function: EarEditField
        function EarEditFieldValueChanged(app, event)
            value = app.EarEditField.Value;
            app.Data.Ear = value;
            app.StartButton.Enable = 'on';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            ReadTestParameters(app);
            app.Data.StartTime = datestr(now);
            delay_samples = appcheckfit(app);
            app.MeasInfoTab.Data(1,1) = cellstr(app.Data.StartTime);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {854, 854};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {293, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Calibration = uimenu(app.UIFigure);
            app.Calibration.Text = 'Calibration';
            app.Subject = uimenu(app.UIFigure);
            app.Subject.Text = 'Subject';
            app.LoadMenu = uimenu(app.Subject);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @loadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.NewMenu = uimenu(app.Subject);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @loadMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.SoundCard = uimenu(app.UIFigure);
            app.SoundCard.Text = 'Sound Card';
            app.ChooseSoundCardMenu = uimenu(app.SoundCard);
            app.ChooseSoundCardMenu.MenuSelectedFcn = createCallbackFcn(app, @ChooseSoundCardMenuSelected, true);
            app.ChooseSoundCardMenu.Text = 'Choose Sound Card';
            app.ResetSoundCardMenu = uimenu(app.SoundCard);
            app.ResetSoundCardMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetSoundCardMenuSelected, true);
            app.ResetSoundCardMenu.Text = 'Reset Sound Card';
            app.Tools = uimenu(app.UIFigure);
            app.Tools.Text = 'Tools';
            app.DataViewerMenu = uimenu(app.Tools);
            app.DataViewerMenu.Text = 'Data Viewer';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutLeft = uigridlayout(app.LeftPanel);
            app.GridMeasParam = uigridlayout(app.GridLayoutLeft);
            app.MaxLeveldBSPLEditFieldLabel = uilabel(app.GridMeasParam);
            app.MaxLeveldBSPLEditFieldLabel.Text = 'Max. Level [dB SPL]';
            app.MaxLeveldBSPLEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.MaxLeveldBSPLEditField.ValueDisplayFormat = '%.1f';
            app.MaxLeveldBSPLEditField.Value = 82;
            app.NoiserejectionleveldBSPLEditFieldLabel = uilabel(app.GridMeasParam);
            app.NoiserejectionleveldBSPLEditFieldLabel.Text = 'Noise rejection level [dB SPL] ';
            app.NoiserejectionleveldBSPLEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.NoiserejectionleveldBSPLEditField.ValueDisplayFormat = '%.1f';
            app.NoiserejectionleveldBSPLEditField.Value = 40;
            app.TimeRejectionmsEditFieldLabel = uilabel(app.GridMeasParam);
            app.TimeRejectionmsEditFieldLabel.Text = 'Time Rejection [ms]';
            app.TimeRejectionmsEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.TimeRejectionmsEditField.ValueDisplayFormat = '%.0f';
            app.TimeRejectionmsEditField.Value = 3.8;
            app.StimulusspacingmsEditFieldLabel = uilabel(app.GridMeasParam);
            app.StimulusspacingmsEditFieldLabel.Text = 'Stimulus spacing [ms]';
            app.StimulusspacingmsEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.StimulusspacingmsEditField.ValueDisplayFormat = '%.0f';
            app.StimulusspacingmsEditField.Value = 20;
            app.NumberofmeasurementsEditFieldLabel = uilabel(app.GridMeasParam);
            app.NumberofmeasurementsEditFieldLabel.Text = 'Number of measurements';
            app.NumberofmeasurementsEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.NumberofmeasurementsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofmeasurementsEditField.Value = 512;
            app.HighPasscutoffHzEditFieldLabel = uilabel(app.GridMeasParam);
            app.HighPasscutoffHzEditFieldLabel.Text = 'High-Pass cutoff [Hz]';
            app.HighPasscutoffHzEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.HighPasscutoffHzEditField.ValueDisplayFormat = '%.0f';
            app.HighPasscutoffHzEditField.Value = 500;
            app.LowpasscutoffHzEditFieldLabel = uilabel(app.GridMeasParam);
            app.LowpasscutoffHzEditFieldLabel.Text = 'Low-pass cutoff [Hz]';
            app.LowpasscutoffHzEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.LowpasscutoffHzEditField.ValueDisplayFormat = '%.0f';
            app.LowpasscutoffHzEditField.Value = 10000;
            app.CheckfitImpulseleveldBSPLEditFieldLabel = uilabel(app.GridMeasParam);
            app.CheckfitImpulseleveldBSPLEditFieldLabel.Text = 'Checkfit Impulse level [dB SPL]';
            app.CheckfitImpulseleveldBSPLEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.CheckfitImpulseleveldBSPLEditField.ValueDisplayFormat = '%.1f';
            app.CheckfitImpulseleveldBSPLEditField.Value = 80;
            app.CheckfitImpulselengthmsEditFieldLabel = uilabel(app.GridMeasParam);
            app.CheckfitImpulselengthmsEditFieldLabel.Text = 'Checkfit Impulse length [ms]';
            app.CheckfitImpulselengthmsEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.CheckfitImpulselengthmsEditField.ValueDisplayFormat = '%.0f';
            app.CheckfitImpulselengthmsEditField.Value = 100;
            app.FrequencyofCheckFitmonitorEditFieldLabel = uilabel(app.GridMeasParam);
            app.FrequencyofCheckFitmonitorEditFieldLabel.Text = 'Frequency of CheckFit  monitor';
            app.FrequencyofCheckFitmonitorEditField = uieditfield(app.GridMeasParam, 'numeric');
            app.FrequencyofCheckFitmonitorEditField.ValueDisplayFormat = '%.0f';
            app.FrequencyofCheckFitmonitorEditField.Value = 20;
            app.ContralateralStimulationSwitchLabel = uilabel(app.GridMeasParam);
            app.ContralateralStimulationSwitchLabel.Text = 'Contralateral Stimulation';
            app.ContralateralStimulationSwitch = uiswitch(app.GridMeasParam, 'slider');
            app.MeasurementParametersLabel = uilabel(app.GridLayoutLeft);
            app.MeasurementParametersLabel.Text = 'Measurement Parameters';
            app.CurrentMeasurementLabel = uilabel(app.GridLayoutLeft);
            app.CurrentMeasurementLabel.Text = 'Current Measurement:';
            app.GridCurrMeas = uigridlayout(app.GridLayoutLeft);
            app.SubjectIDEditFieldLabel = uilabel(app.GridCurrMeas);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.GridCurrMeas, 'text');
            app.SubjectIDEditField.Editable = 'off';
            app.EarEditFieldLabel = uilabel(app.GridCurrMeas);
            app.EarEditFieldLabel.Text = 'Ear';
            app.EarEditField = uieditfield(app.GridCurrMeas, 'text');
            app.EarEditField.ValueChangedFcn = createCallbackFcn(app, @EarEditFieldValueChanged, true);
            app.StartButton = uibutton(app.GridLayoutLeft, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayoutRight = uigridlayout(app.RightPanel);
            app.TEOAEMeasurementLabel = uilabel(app.GridLayoutRight);
            app.TEOAEMeasurementLabel.Text = 'TEOAE Measurement';
            app.GridLayout2 = uigridlayout(app.GridLayoutRight);
            app.AxTimeSig = uiaxes(app.GridLayout2);
            title(app.AxTimeSig, 'Time Signal')
            xlabel(app.AxTimeSig, 'Time [s]')
            ylabel(app.AxTimeSig, 'Pressure [mPa]')
            zlabel(app.AxTimeSig, 'Z')
            app.MeasInfoTab = uitable(app.GridLayout2);
            app.MeasInfoTab.ColumnName = {'Value'};
            app.MeasInfoTab.RowName = {'Date:'; 'Time:'; 'TEOAE Level [dB]:'; 'Noise Level [dB]:'; 'Wave Repro [%]:'; 'Check Fit Peak Level [dB]:'; 'Prev. Check Fit Peak Level [dB]:'};
            app.GridLayout3 = uigridlayout(app.GridLayoutRight);
            app.AXSpecT = uiaxes(app.GridLayout3);
            title(app.AXSpecT, 'Title')
            xlabel(app.AXSpecT, 'Time [ms]')
            ylabel(app.AXSpecT, 'Frequency [kHz]')
            zlabel(app.AXSpecT, 'Z')
            app.GridChedfit = uigridlayout(app.GridLayout3);
            app.UIAxesCheckFitTime = uiaxes(app.GridChedfit);
            title(app.UIAxesCheckFitTime, 'Probe Fit')
            xlabel(app.UIAxesCheckFitTime, 'Time [ms]')
            ylabel(app.UIAxesCheckFitTime, 'Pressure [Pa]')
            zlabel(app.UIAxesCheckFitTime, 'Z')
            app.UIAxesCheckFitFreq = uiaxes(app.GridChedfit);
            xlabel(app.UIAxesCheckFitFreq, 'Frequency [kHz]')
            ylabel(app.UIAxesCheckFitFreq, 'Amplitud [dB]')
            zlabel(app.UIAxesCheckFitFreq, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OAEMeasurement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end