classdef SoundCardSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SelectSoundCardLabel          matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        DoneButton                    matlab.ui.control.Button
        GridLayout3                   matlab.ui.container.GridLayout
        BufferSizesamplesEditField    matlab.ui.control.NumericEditField
        BufferSizesamplesEditFieldLabel  matlab.ui.control.Label
        InputChanelsEditField         matlab.ui.control.EditField
        InputChanelsEditFieldLabel    matlab.ui.control.Label
        OutputChanelsEditField        matlab.ui.control.EditField
        OutputChanelsLabel            matlab.ui.control.Label
        SamplingFrequencyHzEditField  matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Devices % Description
        chosenDev % Description
        mainapp % Description
        AuIO % Description
    end
    
    methods (Access = private)
        function AudioInit(app)
            %% Initialization of full duplex sound card using ASIO driver
            % duplex audio object
            app.AuIO = audioPlayerRecorder;
            % read from user input
            app.AuIO.Device = app.UITable.Data{app.chosenDev.SC};
            app.AuIO.SampleRate = app.chosenDev.SampleRate;
            app.AuIO.PlayerChannelMapping = app.chosenDev.OutChanels;
            app.AuIO.RecorderChannelMapping = app.chosenDev.InChanels;
            % Check the buffer size properties on the ASIO settings to set the same
            % here.
            app.AuIO.SupportVariableSize = true;
            app.AuIO.BufferSize = app.chosenDev.BufferSize;
            % play zeros to lock device for use. 
            try
                [~] = app.AuIO(0.6*zeros(10*app.AuIO.BufferSize,length(app.AuIO.PlayerChannelMapping)));
            catch
                MsG = sprintf('Device: %s is locked or cannot be initialized',app.AuIO.Device);
                uiconfirm(app.UIFigure,MsG,'','Options',{'Ok.'},'icon','error')
            end
       
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            a = audioPlayerRecorder;
            devs = a.getAudioDevices;
            app.Devices = devs;%(contains(devs,'ASIO') | contains(devs, 'Windows WASAPI'));
            app.UITable.Data = app.Devices';
            app.chosenDev.OutChanels = eval(app.OutputChanelsEditField.Value);
            app.chosenDev.InChanels = eval(app.InputChanelsEditField.Value);
            app.chosenDev.SampleRate = app.SamplingFrequencyHzEditField.Value;
            app.chosenDev.BufferSize = app.BufferSizesamplesEditField.Value;
            app.DoneButton.Enable = false;
            
        end

        % Value changed function: BufferSizesamplesEditField, 
        % ...and 3 other components
        function EditFieldValueChanged(app, event)
            app.chosenDev.OutChanels = eval(app.OutputChanelsEditField.Value);
            app.chosenDev.InChanels = eval(app.InputChanelsEditField.Value);
            app.chosenDev.SampleRate = app.SamplingFrequencyHzEditField.Value;
            app.chosenDev.BufferSize = app.BufferSizesamplesEditField.Value;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            %indices = event.Indices;
            app.chosenDev.SC = app.UITable.Selection;
            app.DoneButton.Enable = true;
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            app.chosenDev.SC = app.UITable.Selection;
            app.DoneButton.Enable = true;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            AudioInit(app);
            app.mainapp.AuIO = app.AuIO;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.GridLayout3);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency [Hz]';
            app.SamplingFrequencyHzEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SamplingFrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SamplingFrequencyHzEditField.Value = 48000;
            app.OutputChanelsLabel = uilabel(app.GridLayout3);
            app.OutputChanelsLabel.Text = 'Output Chanels:';
            app.OutputChanelsEditField = uieditfield(app.GridLayout3, 'text');
            app.OutputChanelsEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.OutputChanelsEditField.Value = '[1 2]';
            app.InputChanelsEditFieldLabel = uilabel(app.GridLayout3);
            app.InputChanelsEditFieldLabel.Text = 'Input Chanels:';
            app.InputChanelsEditField = uieditfield(app.GridLayout3, 'text');
            app.InputChanelsEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.InputChanelsEditField.Value = '[1 2]';
            app.BufferSizesamplesEditFieldLabel = uilabel(app.GridLayout3);
            app.BufferSizesamplesEditFieldLabel.Text = 'Buffer Size [samples]';
            app.BufferSizesamplesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.BufferSizesamplesEditField.ValueDisplayFormat = '%.0f';
            app.BufferSizesamplesEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.BufferSizesamplesEditField.Value = 256;
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Available Duplex Sound Cards'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.SelectSoundCardLabel = uilabel(app.GridLayout);
            app.SelectSoundCardLabel.Text = 'Select Sound Card';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoundCardSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end